classdef introToMatlabApps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotSamplesButton           matlab.ui.control.Button
        SamplingRateEditField       matlab.ui.control.NumericEditField
        SamplingRateEditFieldLabel  matlab.ui.control.Label
        FrequencySlider             matlab.ui.control.Slider
        FrequencySliderLabel        matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        freq
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: FrequencySlider
        function FrequencySliderValueChanging(app, event)
            changingValue = event.Value;
            t=(0:.001:2);
            app.freq = changingValue;
            x=cos(2*pi*changingValue*t);
            plot(app.UIAxes, t, x);
            hold(app.UIAxes, 'on');
        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            value = app.SamplingRateEditField.Value;
            n=(0:.1:2);
            x=cos(2*pi*(app.freq/value)*n);
            stem(app.UIAxes, n, x);
            hold(app.UIAxes, 'off');
        end

        % Button pushed function: PlotSamplesButton
        function PlotSamplesButtonPushed(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Continuous and Discrete Signals')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'x(t)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 2];
            app.FrequencySliderLabel = uilabel(app.UIFigure);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.UIFigure);
            app.FrequencySlider.Limits = [1 5];
            app.FrequencySlider.MajorTicks = [1 1.2 1.4 1.6 1.8 2 2.2 2.4 2.6 2.8 3 3.2 3.4 3.6 3.8 4 4.2 4.4 4.6 4.8 5];
            app.FrequencySlider.MajorTickLabels = {'1', '1.2', '1.4', '1.6', '1.8', '2', '2.2', '2.4', '2.6', '2.8', '3', '3.2', '3.4', '3.6', '3.8', '4', '4.2', '4.4', '4.6', '4.8', '5'};
            app.FrequencySlider.ValueChangingFcn = createCallbackFcn(app, @FrequencySliderValueChanging, true);
            app.FrequencySlider.Value = 1;
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.PlotSamplesButton = uibutton(app.UIFigure, 'push');
            app.PlotSamplesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSamplesButtonPushed, true);
            app.PlotSamplesButton.Text = 'Plot Samples';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = introToMatlabApps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end